classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        plotsStatus               matlab.ui.control.Label
        filterStatus              matlab.ui.control.Label
        bandsvaluesLabel          matlab.ui.control.Label
        PlotsButton               matlab.ui.control.Button
        sampleRateInput           matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel  matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        PlayButton                matlab.ui.control.Button
        FilterButton              matlab.ui.control.Button
        FilterTypeButtonGroup     matlab.ui.container.ButtonGroup
        IIR                       matlab.ui.control.RadioButton
        FIR                       matlab.ui.control.RadioButton
        bandSlider9               matlab.ui.control.Slider
        KHzSlider_5Label          matlab.ui.control.Label
        bandSlider8               matlab.ui.control.Slider
        KHzLabel                  matlab.ui.control.Label
        bandSlider7               matlab.ui.control.Slider
        KHzSlider_3Label          matlab.ui.control.Label
        bandSlider6               matlab.ui.control.Slider
        KHzSlider_2Label          matlab.ui.control.Label
        bandSlider5               matlab.ui.control.Slider
        KHzSliderLabel            matlab.ui.control.Label
        bandSlider4               matlab.ui.control.Slider
        HzSlider_4Label           matlab.ui.control.Label
        bandSlider3               matlab.ui.control.Slider
        HzSlider_3Label           matlab.ui.control.Label
        bandSlider2               matlab.ui.control.Slider
        HzSliderLabel             matlab.ui.control.Label
        title                     matlab.ui.control.Label
        bandSlider1               matlab.ui.control.Slider
        HzSlider_2Label           matlab.ui.control.Label
        chooseFileButton          matlab.ui.control.Button
        currentFile               matlab.ui.control.Label
        FileNameLabel             matlab.ui.control.Label
        filteredPlot              matlab.ui.control.UIAxes
        originalPlot              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        file_name
        file_path
        filter_type = 0;
        bands = ones(1, 9);
        sample_rate = 0;
        audio_data;
        default_audio_data;
        default_sample_rate = 0;
        filtered_audio_data;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: chooseFileButton
        function chooseFileButtonPushed(app, event)
            [f_name,f_path] = uigetfile({'*.mp3'},'File Selector');
            if(f_name ~= 0)
                app.file_name = f_name;
                app.file_path = strcat(f_path, f_name);
                [app.default_audio_data, app.default_sample_rate] = audioread(app.file_path); % reads the audio data
                app.sampleRateInput.Value = app.default_sample_rate; % shows the file sample rate in the input box
                app.sample_rate = app.default_sample_rate;
                app.audio_data = app.default_audio_data;
                app.currentFile.Text = app.file_name; % updates current file
            end
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterTypeButtonGroup.SelectedObject;
            if(selectedButton == app.FIR)
                app.filter_type = 0;
            else
                app.filter_type = 1;
            end
        end

        % Value changed function: bandSlider1
        function bandSlider1ValueChanged(app, event)
            value = app.bandSlider1.Value;
            app.bands(1) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider2
        function bandSlider2ValueChanged(app, event)
            value = app.bandSlider2.Value;
            app.bands(2) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider3
        function bandSlider3ValueChanged(app, event)
            value = app.bandSlider3.Value;
            app.bands(3) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider4
        function bandSlider4ValueChanged(app, event)
            value = app.bandSlider4.Value;
            app.bands(4) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider5
        function bandSlider5ValueChanged(app, event)
            value = app.bandSlider5.Value;
            app.bands(5) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider6
        function bandSlider6ValueChanged(app, event)
            value = app.bandSlider6.Value;
            app.bands(6) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider7
        function bandSlider7ValueChanged(app, event)
            value = app.bandSlider7.Value;
            app.bands(7) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider8
        function bandSlider8ValueChanged(app, event)
            value = app.bandSlider8.Value;
            app.bands(8) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: bandSlider9
        function bandSlider9ValueChanged(app, event)
            value = app.bandSlider9.Value;
            app.bands(9) = 10^(value/20);
            app.bandsvaluesLabel.Text = mat2str(app.bands,2);
        end

        % Value changed function: sampleRateInput
        function sampleRateInputValueChanged(app, event)
            value = app.sampleRateInput.Value;
            app.sample_rate = value;
            %% interpolation or expolation
            if(app.sample_rate == app.default_sample_rate)
                app.audio_data = app.default_audio_data;
            elseif (app.sample_rate > app.default_sample_rate)
                if(mod(app.sample_rate,app.default_sample_rate) == 0)
                    app.audio_data = upsample(app.default_audio_data, app.sample_rate/app.default_sample_rate);
                else
                    app.sample_rate = app.default_sample_rate;
                    app.sampleRateInput.Value = app.default_sample_rate;
                end
            else
                if(mod(app.default_sample_rate,app.sample_rate) == 0)
                    app.audio_data = downsample(app.default_audio_data, app.default_sample_rate/app.sample_rate);
                else
                    app.sample_rate = app.default_sample_rate;
                    app.sampleRateInput.Value = app.default_sample_rate;
                end
            end
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            app.filterStatus.Text = "Filtering started";
            if(app.filter_type == 0)
                app.filterStatus.Text = "Filtering using FIR";
                app.filtered_audio_data = apply_fir_filters(app.audio_data, app.default_sample_rate, app.bands);
            else
                app.filterStatus.Text = "Filtering using IIR";
                app.filtered_audio_data = apply_iir_filters(app.audio_data, app.default_sample_rate, app.bands);
            end
            app.filterStatus.Text = "Filtering ended";
            app.filterStatus.Text = "Plotting started";
            plot(app.originalPlot,app.audio_data);
            plot(app.filteredPlot,app.filtered_audio_data);
            app.filterStatus.Text = "Plotting ended";
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.filtered_audio_data,app.default_sample_rate); % plays filtered sound for now
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound; % stops filtered sound for now
        end

        % Button pushed function: PlotsButton
        function PlotsButtonPushed(app, event)
            app.plotsStatus.Text = "generating plots";
            %plotting input in t
            f = figure('visible','off');
            plot(app.audio_data);
            saveas(f,'fig_input_t','jpg');
            %plotting input in f
            audio_len = length(app.audio_data);
            FFT_audio = fft(app.audio_data);
            P2 = abs(FFT_audio/audio_len);
            P1 = P2(1:audio_len/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            freq_audio = app.default_sample_rate*(0:(audio_len/2))/audio_len;
            plot(freq_audio,P1);
            saveas(f,'fig_input_f','jpg');
            %plotting different filtered bands and composite output
            plot_fir_filters(app.audio_data,app.default_sample_rate,app.bands);
            plot_iir_filters(app.audio_data,app.default_sample_rate,app.bands);
            app.plotsStatus.Text = "generation ended";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = 'filtered.wav';
            audiowrite(filename,app.filtered_audio_data,app.default_sample_rate);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.originalPlot = uiaxes(app.UIFigure);
            title(app.originalPlot, 'Original')
            xlabel(app.originalPlot, 'X')
            ylabel(app.originalPlot, 'Y')
            app.filteredPlot = uiaxes(app.UIFigure);
            title(app.filteredPlot, 'Filtered')
            xlabel(app.filteredPlot, 'X')
            ylabel(app.filteredPlot, 'Y')
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name:';
            app.currentFile = uilabel(app.UIFigure);
            app.currentFile.Text = 'Choose a file ...';
            app.chooseFileButton = uibutton(app.UIFigure, 'push');
            app.chooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileButtonPushed, true);
            app.chooseFileButton.Text = 'Choose';
            app.HzSlider_2Label = uilabel(app.UIFigure);
            app.HzSlider_2Label.Text = '0 - 170 Hz';
            app.bandSlider1 = uislider(app.UIFigure);
            app.bandSlider1.Limits = [-10 10];
            app.bandSlider1.ValueChangedFcn = createCallbackFcn(app, @bandSlider1ValueChanged, true);
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Audio Equalizer';
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = '170 - 310 Hz';
            app.bandSlider2 = uislider(app.UIFigure);
            app.bandSlider2.Limits = [-10 10];
            app.bandSlider2.ValueChangedFcn = createCallbackFcn(app, @bandSlider2ValueChanged, true);
            app.HzSlider_3Label = uilabel(app.UIFigure);
            app.HzSlider_3Label.Text = '310 - 600 Hz';
            app.bandSlider3 = uislider(app.UIFigure);
            app.bandSlider3.Limits = [-10 10];
            app.bandSlider3.ValueChangedFcn = createCallbackFcn(app, @bandSlider3ValueChanged, true);
            app.HzSlider_4Label = uilabel(app.UIFigure);
            app.HzSlider_4Label.Text = '600 - 1000 Hz';
            app.bandSlider4 = uislider(app.UIFigure);
            app.bandSlider4.Limits = [-10 10];
            app.bandSlider4.ValueChangedFcn = createCallbackFcn(app, @bandSlider4ValueChanged, true);
            app.KHzSliderLabel = uilabel(app.UIFigure);
            app.KHzSliderLabel.Text = '1 - 3 KHz';
            app.bandSlider5 = uislider(app.UIFigure);
            app.bandSlider5.Limits = [-10 10];
            app.bandSlider5.ValueChangedFcn = createCallbackFcn(app, @bandSlider5ValueChanged, true);
            app.KHzSlider_2Label = uilabel(app.UIFigure);
            app.KHzSlider_2Label.Text = '3 - 6 KHz';
            app.bandSlider6 = uislider(app.UIFigure);
            app.bandSlider6.Limits = [-10 10];
            app.bandSlider6.ValueChangedFcn = createCallbackFcn(app, @bandSlider6ValueChanged, true);
            app.KHzSlider_3Label = uilabel(app.UIFigure);
            app.KHzSlider_3Label.Text = '6 - 12 KHz';
            app.bandSlider7 = uislider(app.UIFigure);
            app.bandSlider7.Limits = [-10 10];
            app.bandSlider7.ValueChangedFcn = createCallbackFcn(app, @bandSlider7ValueChanged, true);
            app.KHzLabel = uilabel(app.UIFigure);
            app.KHzLabel.Text = '12 - 14 KHz';
            app.bandSlider8 = uislider(app.UIFigure);
            app.bandSlider8.Limits = [-10 10];
            app.bandSlider8.ValueChangedFcn = createCallbackFcn(app, @bandSlider8ValueChanged, true);
            app.KHzSlider_5Label = uilabel(app.UIFigure);
            app.KHzSlider_5Label.Text = '14 - 16 KHz';
            app.bandSlider9 = uislider(app.UIFigure);
            app.bandSlider9.Limits = [-10 10];
            app.bandSlider9.ValueChangedFcn = createCallbackFcn(app, @bandSlider9ValueChanged, true);
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.FIR = uiradiobutton(app.FilterTypeButtonGroup);
            app.FIR.Text = 'FIR';
            app.FIR.Value = true;
            app.IIR = uiradiobutton(app.FilterTypeButtonGroup);
            app.IIR.Text = 'IIR';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.sampleRateInput = uieditfield(app.UIFigure, 'numeric');
            app.sampleRateInput.RoundFractionalValues = 'on';
            app.sampleRateInput.ValueChangedFcn = createCallbackFcn(app, @sampleRateInputValueChanged, true);
            app.PlotsButton = uibutton(app.UIFigure, 'push');
            app.PlotsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsButtonPushed, true);
            app.PlotsButton.Text = 'Plots';
            app.bandsvaluesLabel = uilabel(app.UIFigure);
            app.bandsvaluesLabel.Text = 'bands values';
            app.filterStatus = uilabel(app.UIFigure);
            app.filterStatus.Text = 'Filtering Status ..';
            app.plotsStatus = uilabel(app.UIFigure);
            app.plotsStatus.Text = 'Plots Status ...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end